classdef LynxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        EditFieldLabel             matlab.ui.control.Label
        theta2_rad                 matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        theta3_rad                 matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        theta4_rad                 matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        theta5_rad                 matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        theta1_deg                 matlab.ui.control.NumericEditField
        theta2_deg                 matlab.ui.control.NumericEditField
        theta3_deg                 matlab.ui.control.NumericEditField
        LynxRobotJointAnglesLabel  matlab.ui.control.Label
        RadiansLabel               matlab.ui.control.Label
        DegreesLabel               matlab.ui.control.Label
        LynxRobotEEFrameLabel      matlab.ui.control.Label
        x                          matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        y                          matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        z                          matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        psi_deg                    matlab.ui.control.NumericEditField
        phi_deg                    matlab.ui.control.NumericEditField
        psi_rad                    matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        phi_rad                    matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        RadiansLabel_2             matlab.ui.control.Label
        DegreesLabel_2             matlab.ui.control.Label
        theta4_deg                 matlab.ui.control.NumericEditField
        theta5_deg                 matlab.ui.control.NumericEditField
        theta1_rad                 matlab.ui.control.NumericEditField
    end

    
    properties(Access = private)
        theta1 = 0
        theta2 = 0
        theta3 = 0
        theta4 = 0
        theta5 = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            
        end

        % Value changed function: theta1_rad
        function theta1_radValueChanged(app, event)
            app.theta1 = app.theta1_rad.Value;
            app.theta1_deg.Value = app.theta1 * 180 / pi;
        end

        % Value changed function: theta2_rad
        function theta2_radValueChanged(app, event)
            app.theta2 = app.theta2_rad.Value;
            app.theta2_deg.Value = app.theta2 * 180 / pi;
        end

        % Value changed function: theta3_rad
        function theta3_radValueChanged(app, event)
            app.theta3 = app.theta3_rad.Value;
            app.theta3_deg.Value = app.theta3 * 180 / pi;
        end

        % Value changed function: theta4_rad
        function theta4_radValueChanged(app, event)
            app.theta4 = app.theta4_rad.Value;
            app.theta4_deg.Value = app.theta4 * 180 / pi;
        end

        % Value changed function: theta5_rad
        function theta5_radValueChanged(app, event)
            app.theta5 = app.theta5_rad.Value;
            app.theta5_deg.Value = app.theta5 * 180 / pi;
        end

        % Value changed function: theta1_deg
        function theta1_degValueChanged(app, event)
            app.theta1 = app.theta1_deg.Value * pi / 180;
            app.theta1_rad.Value = app.theta1;
        end

        % Value changed function: theta2_deg
        function theta2_degValueChanged(app, event)
            app.theta2 = app.theta2_deg.Value * pi / 180;
            app.theta2_rad.Value = app.theta2;
        end

        % Value changed function: theta3_deg
        function theta3_degValueChanged(app, event)
            app.theta3 = app.theta3_deg.Value * pi / 180;
            app.theta3_rad.Value = app.theta3;
        end

        % Value changed function: theta4_deg
        function theta4_degValueChanged(app, event)
            app.theta4 = app.theta4_deg.Value * pi / 180;
            app.theta4_rad.Value = app.theta4;
        end

        % Value changed function: theta5_deg
        function theta5_degValueChanged(app, event)
            app.theta5 = app.theta5_deg.Value * pi / 180;
            app.theta5_rad.Value = app.theta5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'θ1';
            app.theta1_rad = uieditfield(app.UIFigure, 'numeric');
            app.theta1_rad.Limits = [-1.9199 1.5708];
            app.theta1_rad.ValueDisplayFormat = '%11.3g';
            app.theta1_rad.ValueChangedFcn = createCallbackFcn(app, @theta1_radValueChanged, true);
            app.theta2_rad = uieditfield(app.UIFigure, 'numeric');
            app.theta2_rad.Limits = [-3.14159265358979 3.14159265358979];
            app.theta2_rad.ValueDisplayFormat = '%11.3g';
            app.theta2_rad.ValueChangedFcn = createCallbackFcn(app, @theta2_radValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'θ2';
            app.theta3_rad = uieditfield(app.UIFigure, 'numeric');
            app.theta3_rad.Limits = [0 2.618];
            app.theta3_rad.ValueDisplayFormat = '%11.3g';
            app.theta3_rad.ValueChangedFcn = createCallbackFcn(app, @theta3_radValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'θ3';
            app.theta4_rad = uieditfield(app.UIFigure, 'numeric');
            app.theta4_rad.Limits = [-2.0071 1.7453];
            app.theta4_rad.ValueDisplayFormat = '%11.3g';
            app.theta4_rad.ValueChangedFcn = createCallbackFcn(app, @theta4_radValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'θ4';
            app.theta5_rad = uieditfield(app.UIFigure, 'numeric');
            app.theta5_rad.Limits = [-1.5708 1.9199];
            app.theta5_rad.ValueDisplayFormat = '%11.3g';
            app.theta5_rad.ValueChangedFcn = createCallbackFcn(app, @theta5_radValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'θ5';
            app.theta1_deg = uieditfield(app.UIFigure, 'numeric');
            app.theta1_deg.Limits = [-110 90];
            app.theta1_deg.ValueDisplayFormat = '%11.3g';
            app.theta1_deg.ValueChangedFcn = createCallbackFcn(app, @theta1_degValueChanged, true);
            app.theta2_deg = uieditfield(app.UIFigure, 'numeric');
            app.theta2_deg.Limits = [-90 90];
            app.theta2_deg.ValueDisplayFormat = '%11.3g';
            app.theta2_deg.ValueChangedFcn = createCallbackFcn(app, @theta2_degValueChanged, true);
            app.theta3_deg = uieditfield(app.UIFigure, 'numeric');
            app.theta3_deg.Limits = [0 150];
            app.theta3_deg.ValueDisplayFormat = '%11.3g';
            app.theta3_deg.ValueChangedFcn = createCallbackFcn(app, @theta3_degValueChanged, true);
            app.theta4_deg = uieditfield(app.UIFigure, 'numeric');
            app.theta4_deg.Limits = [-115 100];
            app.theta4_deg.ValueDisplayFormat = '%11.3g';
            app.theta4_deg.ValueChangedFcn = createCallbackFcn(app, @theta4_degValueChanged, true);
            app.theta5_deg = uieditfield(app.UIFigure, 'numeric');
            app.theta5_deg.Limits = [-90 110];
            app.theta5_deg.ValueDisplayFormat = '%11.3g';
            app.theta5_deg.ValueChangedFcn = createCallbackFcn(app, @theta5_degValueChanged, true);
            app.LynxRobotJointAnglesLabel = uilabel(app.UIFigure);
            app.LynxRobotJointAnglesLabel.Text = 'Lynx Robot Joint Angles';
            app.RadiansLabel = uilabel(app.UIFigure);
            app.RadiansLabel.Text = 'Radians';
            app.DegreesLabel = uilabel(app.UIFigure);
            app.DegreesLabel.Text = 'Degrees';
            app.LynxRobotEEFrameLabel = uilabel(app.UIFigure);
            app.LynxRobotEEFrameLabel.Text = 'Lynx Robot EE Frame';
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.y = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.z = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.psi_deg = uieditfield(app.UIFigure, 'numeric');
            app.phi_deg = uieditfield(app.UIFigure, 'numeric');
            app.psi_rad = uieditfield(app.UIFigure, 'numeric');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'ψ';
            app.phi_rad = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'φ';
            app.RadiansLabel_2 = uilabel(app.UIFigure);
            app.RadiansLabel_2.Text = 'Radians';
            app.DegreesLabel_2 = uilabel(app.UIFigure);
            app.DegreesLabel_2.Text = 'Degrees';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LynxApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end